classdef climatewedges < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        WedgesFileNameWithoutQuotesEditFieldLabel  matlab.ui.control.Label
        WedgesFileNameWithoutQuotesEditField  matlab.ui.control.EditField
        ConstraintsFileNameWithoutQuotesEditFieldLabel  matlab.ui.control.Label
        ConstraintsFileNameWithoutQuotesEditField  matlab.ui.control.EditField
        StrategyFileNameWithoutQuotesEditFieldLabel  matlab.ui.control.Label
        StrategyFileNameWithoutQuotesEditField  matlab.ui.control.EditField
        SelectedWedgesTextAreaLabel  matlab.ui.control.Label
        SelectedWedgesTextArea       matlab.ui.control.TextArea
        ReadFilesButton              matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        WedgeNumbersDropDownLabel    matlab.ui.control.Label
        WedgeNumbersDropDown         matlab.ui.control.DropDown
        UIAxes                       matlab.ui.control.UIAxes
        SavetheWorldLabel            matlab.ui.control.Label
        OutputStrategyButton         matlab.ui.control.Button
        WedgesStartYearOnlyforwhenStrategyfileisnotincludedSliderLabel  matlab.ui.control.Label
        WedgesStartYearOnlyforwhenStrategyfileisnotincludedSlider  matlab.ui.control.Slider
        Step1ReadFilesLabel          matlab.ui.control.Label
        Step2SelectStartingYearIfStrategyFileisnotIncludedLabel  matlab.ui.control.Label
        Step3SelectAdditionalWedgesLabel  matlab.ui.control.Label
        Step4OutputStrategyLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        wedge_data;
        header;
        XData;
        YData;
        Constraints;
        Strategies;
        Count;
        Output;
        Carbon_emissions;
        % Initialize variables
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fileID = fopen('historicaldata.txt','r');
            header1 = fgetl(fileID);
            app.Carbon_emissions = 10*2^((2068-2017)/50);
            
            data = [];
            while ~feof(fileID)
                getdata = fgetl(fileID);
                u = str2num(getdata);
                data = [data; u];
            end
            
            app.XData = data(:,1);
            app.XData = [app.XData; 2018; 2068];
            app.YData = data(:,2);
            app.YData = [app.YData; 10*2^((2018-2017)/50); app.Carbon_emissions];
            
            
            plot(app.UIAxes,app.XData,app.YData, '-g');
            app.UIAxes.XLim = [1959, 2068];
            
        end

        % Button pushed function: ReadFilesButton
        function ReadFilesButtonPushed(app, event)
            value1 = app.WedgesFileNameWithoutQuotesEditField.Value;
            % Initialize variables
            % Make Sounds for errors when applicable
            % Use Warndlg for error
            
            try
                if strfind(value1,'wedges')
                    FID = fopen(value1);
                    app.header = fgetl(FID);
                    % If file is correct, open text file and extract first line
                    
                    app.UITable.ColumnName = strsplit(app.header, ';');
                    % Make first line of file the header of the table
                    app.wedge_data = {};
                    while ~feof(FID)
                        x = strsplit(fgetl(FID), ';');
                        app.wedge_data = [app.wedge_data ; x];
                    end % end while
                    % Extract each line of the file
                    
                    app.UITable.Data = {};
                    app.UITable.Data = app.wedge_data;
                    % Display the data in the Table
                    
                    dropmenu = app.wedge_data(:, 1);
                    dropmenu = [dropmenu; {'Clear all'}; {' '}];
                    
                    app.WedgeNumbersDropDown.Items = dropmenu;
                    % Display wedge numbers in the Drop Down Menu
                    
                    app.WedgeNumbersDropDown.Value = [' '];
                    
                elseif isempty(value1)
                else
                    warndlg('Incorrect wedges file name entered. File name must include the word ''wedges''.', 'Error');
                    return
                    % If the wrong file is selected, display error message
                end % end if
            catch
                warndlg('Wedges file not found in MATLAB path', 'Error');
                return
                % If the file is not in the MATLAB path, display the error message
            end % end try
            % ------------------------------------------------------------------------------------------------------------------------------------------------
            value2 = app.ConstraintsFileNameWithoutQuotesEditField.Value;
            data = {};
            % Initialize variables
            
            try
                if strfind(value2,'constraints')
                    % If the correct file is selected, extract each line
                    % May want to use strsplit to extract data
                    FID = fopen(value2);
                    while ~feof(FID)
                        x = strsplit(fgetl(FID), ' ');
                        data = [data; x];
                    end % end while
                    app.Constraints = data;
                    % Display the data in the appropriate text area


                elseif isempty(value2)
                else
                    warndlg('Incorrect constraints file name entered. File name must include the word ''constraints''.', 'Error');
                    return
                    % If the wrong file is selected, display error message
                end % end if
            catch
                warndlg('Constraints file not found in MATLAB path', 'Error');
                return
                % If the file is not in the MATLAB path, display error message
            end % end try
            % ------------------------------------------------------------------------------------------------------------------------------------------------
            value3 = app.StrategyFileNameWithoutQuotesEditField.Value;
            data = {};
            % Initialize variables
            
            try
                if strfind(value3,'strategy')
                    % If the correct file is selected, extract each line
                    % May want to use strsplit to extract data
                    FID = fopen(value3);
                    while ~feof(FID)
                        x = strsplit(fgetl(FID), ' ');
                        data = [data; x];
                    end % end while


                    header3 = data(1, :);
                    [r, ~] = size(data);
                    header1 = strsplit(app.header, ';');
                    [r2, ~] = size(app.Constraints);
                    
                    
                    app.SelectedWedgesTextArea.Value = [' '];
                    for i = 2:r
                        index1 = find(contains(header3, 'ID'));
                        index2 = find(contains(header1, 'Strategy'));
                        wedge = [char(data{i, index1}), ' ', char(app.wedge_data{str2num(data{i,index1}), index2})];
                        app.SelectedWedgesTextArea.Value = [app.SelectedWedgesTextArea.Value; wedge];
                    end % end for
                elseif isempty(value3)
                    return
                else
                    warndlg('Incorrect strategy file name entered. File name must include the word ''strategy''.', 'Error');
                    return
                    % If the wrong file is selected, display error message
                end % end if
            catch
                warndlg('Strategy file not found in MATLAB path', 'Error');
                return
                % If the file is not in the MATLAB path, display error message
            end % end try
            
            app.Strategies = data;
            
            header1 = strsplit(app.header, ';');
            header4 = app.Constraints(1,:);
            [r2, ~] = size(app.Constraints);
            header3 = data(1, :);
            
            index1 = find(contains(header1, 'Strategy'));
            index3 = find(contains(header1, 'Sector'));
            index4 = find(contains(header4, 'Sector'));
            index5 = find(contains(header4, 'Constraints'));
            index6 = find(contains(header3, 'ID'));
            index7 = find(contains(header1, 'Category'));
            index8 = find(contains(header3, 'YearStart'));
            
            
            for j = 2:r2
                Constraint = strsplit(app.wedge_data{str2num(data{j, index6}), index3}, ',');
                for m = 2:r2
                    for k = 1:numel(Constraint)
                        if strcmp(string(Constraint(k)), string(app.Constraints{m, index4}))
                            app.Constraints{m, index5} = num2str(str2num(app.Constraints{m, index5})-1);
                        end % end if
                        if str2num(app.Constraints{j, index5}) <= 0
                            warndlg('Too many files of desired sector. Please follow given constraints.');
                            return
                        end % end if
                    end % end for
                end % end for
            end % end for
            
            hold(app.UIAxes,'on');
            
            app.WedgesStartYearOnlyforwhenStrategyfileisnotincludedSlider.Value = str2num(data{j, index8});
            m = (10*2^((2068-2017)/50)-10*2^((2018-2017)/50))/(2068-2018);
            b = (10*2^((2018-2017)/50)-m*2018);
            
            for j = 2:r
                
                xdata1 = [str2num(data{j, index8}), 2068];
                ydata1 = [m*str2num(data{j, index8})+b, app.Carbon_emissions - (2068-str2num(data{j, index8}))/50*(j-2)];
                plot(app.UIAxes,xdata1,ydata1);
                [r, ~] = size(data);
                
                
                xdata2 = [str2num(data{j, index8}), 2068];
                ydata2 = [m*str2num(data{j, index8})+b, app.Carbon_emissions - (2068-str2num(data{j, index8}))/50*(j-1)];
                plot(app.UIAxes, xdata2, ydata2);


                Category = strsplit(app.wedge_data{str2num(data{j, index6}), index7}, ',');
                if strcmp('Efficiency and Conservation', Category)
                    fill(app.UIAxes, [xdata1, xdata2(2)], [ydata1, ydata2(2)], 'y');
                elseif strcmp('Fossil Fuel', Category)
                    fill(app.UIAxes, [xdata1, xdata2(2)], [ydata1, ydata2(2)], 'b');
                elseif strcmp('Nuclear', Category)
                    fill(app.UIAxes, [xdata1, xdata2(2)], [ydata1, ydata2(2)], 'r');
                elseif strcmp('Renewables and Biostorage', Category)
                    fill(app.UIAxes, [xdata1, xdata2(2)], [ydata1, ydata2(2)], 'g');
                end % end if
            end % end for


        end

        % Value changed function: WedgeNumbersDropDown
        function WedgeNumbersDropDownValueChanged(app, event)
                value1 = app.WedgeNumbersDropDown.Value;
                value = str2num(value1);
                header1 = strsplit(app.header, ';');
                header4 = app.Constraints(1,:);
                [r2, ~] = size(app.Constraints);
                header5 = app.Strategies(1,:);
                header6 = app.Constraints(:, 1);
                
                if strcmp(value1, 'Clear all') == 0
                    index1 = find(contains(header1, 'Strategy'));
                    index3 = find(contains(header1, 'Sector'));
                    index4 = find(contains(header4, 'Sector'));
                    index5 = find(contains(header4, 'Constraints'));
                    index7 = find(contains(header1, 'Category'));
                    index8 = find(contains(header5, 'YearStart'));
                    for j = 2:r2
                        Constraint = strsplit(app.wedge_data{value, index3}, ',');
                        for k = 1:numel(Constraint)
                            if strcmp(string(Constraint(k)), string(app.Constraints{j, index4}))
                                app.Constraints{j, index5} = num2str(str2num(app.Constraints{j, index5})-1);
                            end % end if
                            try r = 3;
                                if str2num(app.Constraints{find(contains(header6, Constraint(k))), index5}) <= 0
                                warndlg('Too many files of desired sector. Please follow given constraints.');
                                return
                                end % end if
                            catch
                            end % end try
                        end % end for
                    end % end for
                    
                    
                    wedge = [num2str(value), ' ', char(app.wedge_data{value, index1})];
                    app.SelectedWedgesTextArea.Value = [app.SelectedWedgesTextArea.Value; wedge];
                    
                    
                    j = numel(app.SelectedWedgesTextArea.Value);
                    
                    m = (10*2^((2068-2017)/50)-10*2^((2018-2017)/50))/(2068-2018);
            b = (10*2^((2018-2017)/50)-m*2018);
            year = app.WedgesStartYearOnlyforwhenStrategyfileisnotincludedSlider.Value;
                    
                     xdata1 = [year, 2068];
                ydata1 = [m*year+b, app.Carbon_emissions - (2068-year)/50*(j-2)];
                plot(app.UIAxes,xdata1,ydata1);
                
                
                xdata2 = [year, 2068];
                ydata2 = [m*year+b, app.Carbon_emissions - (2068-year)/50*(j-1)];
                plot(app.UIAxes, xdata2, ydata2);


                Category = strsplit(app.wedge_data{value, index7}, ',');
                if strcmp('Efficiency and Conservation', Category)
                    fill(app.UIAxes, [xdata1, xdata2(2)], [ydata1, ydata2(2)], 'y');
                elseif strcmp('Fossil Fuel', Category)
                    fill(app.UIAxes, [xdata1, xdata2(2)], [ydata1, ydata2(2)], 'b');
                elseif strcmp('Nuclear', Category)
                    fill(app.UIAxes, [xdata1, xdata2(2)], [ydata1, ydata2(2)], 'r');
                elseif strcmp('Renewables and Biostorage', Category)
                    fill(app.UIAxes, [xdata1, xdata2(2)], [ydata1, ydata2(2)], 'g');
                end % end if
                else
                    app.SelectedWedgesTextArea.Value = [' '];
                end % end if
                app.WedgeNumbersDropDown.Value = ' ';
                
                 hold(app.UIAxes,'on');


        end

        % Button pushed function: OutputStrategyButton
        function OutputStrategyButtonPushed(app, event)
                
                
                header1 = strsplit(app.header, ';');
                fid = fopen('output.txt', 'wt');
                headerline = ['ID;StrategyName;Sector;NumberUsed;TotalCost;Challenges'];
                fprintf(fid, headerline);
                app.Output = [];


                for r = 2:numel(app.SelectedWedgesTextArea.Value)-1
                    app.Count = 1;
                    for q = r+1:numel(app.SelectedWedgesTextArea.Value)
                        if strcmp(app.SelectedWedgesTextArea.Value(r), app.SelectedWedgesTextArea.Value(q))
                            app.Count = app.Count + 1;
                        end % end if
                    end % end for
                    
                    if app.Count > numel(app.SelectedWedgesTextArea.Value)/2 && app.Count > 1
                        warndlg('One wedge cannot account for more than half of the selected wedges', 'Error');
                        return
                    end % end if
                    
                    index1 = find(contains(header1, 'ID'));
                    index2 = find(contains(header1, 'StrategyName'));
                    index3 = find(contains(header1, 'Sector'));
                    index5 = find(contains(header1, 'Cost'));
                    index6 = find(contains(header1, 'Challenges'));
                    
                    
                    IDnumber1 = strsplit(char(app.SelectedWedgesTextArea.Value(r)));
                    IDnumber2 = str2num(char(IDnumber1{1}));
                    
                    line = [app.wedge_data{IDnumber2, index1}, ';', app.wedge_data{IDnumber2, index2}, ';', app.wedge_data{IDnumber2, index3}, ';', num2str(app.Count), ';', num2str(str2num(char(app.wedge_data{IDnumber2, index5}))*app.Count), ';', app.wedge_data{IDnumber2, index6}];


                    fprintf(fid, '\n%s', line)


                end % end for
                
                
                r = numel(app.SelectedWedgesTextArea.Value);
                
                IDnumber1 = strsplit(char(app.SelectedWedgesTextArea.Value(r)));
                IDnumber2 = str2num(char(IDnumber1{1}));
                
                line = [app.wedge_data{IDnumber2, index1}, ';', app.wedge_data{IDnumber2, index2}, ';', app.wedge_data{IDnumber2, index3}, ';', num2str(app.Count), ';', num2str(str2num(char(app.wedge_data{IDnumber2, index5}))*app.Count), ';', app.wedge_data{IDnumber2, index6}];
                
                fprintf(fid, '\n%s\n', line)


                fclose(fid);


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.WedgesFileNameWithoutQuotesEditFieldLabel = uilabel(app.UIFigure);
            app.WedgesFileNameWithoutQuotesEditFieldLabel.Text = 'Wedges File Name (Without Quotes)';
            app.WedgesFileNameWithoutQuotesEditField = uieditfield(app.UIFigure, 'text');
            app.WedgesFileNameWithoutQuotesEditField.Value = 'wedges.txt';
            app.ConstraintsFileNameWithoutQuotesEditFieldLabel = uilabel(app.UIFigure);
            app.ConstraintsFileNameWithoutQuotesEditFieldLabel.Text = 'Constraints File Name (Without Quotes)';
            app.ConstraintsFileNameWithoutQuotesEditField = uieditfield(app.UIFigure, 'text');
            app.ConstraintsFileNameWithoutQuotesEditField.Value = 'constraints.txt';
            app.StrategyFileNameWithoutQuotesEditFieldLabel = uilabel(app.UIFigure);
            app.StrategyFileNameWithoutQuotesEditFieldLabel.Text = 'Strategy File Name (Without Quotes)';
            app.StrategyFileNameWithoutQuotesEditField = uieditfield(app.UIFigure, 'text');
            app.StrategyFileNameWithoutQuotesEditField.Value = 'strategy.txt';
            app.SelectedWedgesTextAreaLabel = uilabel(app.UIFigure);
            app.SelectedWedgesTextAreaLabel.Text = 'Selected Wedges';
            app.SelectedWedgesTextArea = uitextarea(app.UIFigure);
            app.ReadFilesButton = uibutton(app.UIFigure, 'push');
            app.ReadFilesButton.ButtonPushedFcn = createCallbackFcn(app, @ReadFilesButtonPushed, true);
            app.ReadFilesButton.Text = 'Read Files';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.WedgeNumbersDropDownLabel = uilabel(app.UIFigure);
            app.WedgeNumbersDropDownLabel.Text = 'Wedge Numbers';
            app.WedgeNumbersDropDown = uidropdown(app.UIFigure);
            app.WedgeNumbersDropDown.Items = {'1', '2', '3', '4'};
            app.WedgeNumbersDropDown.ValueChangedFcn = createCallbackFcn(app, @WedgeNumbersDropDownValueChanged, true);
            app.WedgeNumbersDropDown.Value = '1';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Carbon Emissions over Time')
            xlabel(app.UIAxes, 'Year')
            ylabel(app.UIAxes, 'Carbon Emissions (Gt/yr)')
            app.SavetheWorldLabel = uilabel(app.UIFigure);
            app.SavetheWorldLabel.Text = 'Save the World !';
            app.OutputStrategyButton = uibutton(app.UIFigure, 'push');
            app.OutputStrategyButton.ButtonPushedFcn = createCallbackFcn(app, @OutputStrategyButtonPushed, true);
            app.OutputStrategyButton.Text = 'Output Strategy';
            app.WedgesStartYearOnlyforwhenStrategyfileisnotincludedSliderLabel = uilabel(app.UIFigure);
            app.WedgesStartYearOnlyforwhenStrategyfileisnotincludedSliderLabel.Text = 'Wedges Start Year (Only for when Strategy file is not included)';
            app.WedgesStartYearOnlyforwhenStrategyfileisnotincludedSlider = uislider(app.UIFigure);
            app.WedgesStartYearOnlyforwhenStrategyfileisnotincludedSlider.Limits = [2018 2067];
            app.WedgesStartYearOnlyforwhenStrategyfileisnotincludedSlider.Value = 2018;
            app.Step1ReadFilesLabel = uilabel(app.UIFigure);
            app.Step1ReadFilesLabel.Text = 'Step 1: Read Files';
            app.Step2SelectStartingYearIfStrategyFileisnotIncludedLabel = uilabel(app.UIFigure);
            app.Step2SelectStartingYearIfStrategyFileisnotIncludedLabel.Text = 'Step 2: Select Starting Year (If Strategy File is not Included)';
            app.Step3SelectAdditionalWedgesLabel = uilabel(app.UIFigure);
            app.Step3SelectAdditionalWedgesLabel.Text = 'Step 3: Select Additional Wedges';
            app.Step4OutputStrategyLabel = uilabel(app.UIFigure);
            app.Step4OutputStrategyLabel.Text = 'Step 4: Output Strategy';
        end
    end

    methods (Access = public)

        % Construct app
        function app = climatewedges

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end